classdef InterfaceG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        PlotarimagemButton             matlab.ui.control.Button
        AdicionarDiaSelecionadoButton  matlab.ui.control.Button
        readediasselecionadosLabel     matlab.ui.control.Label
        DiasTextArea                   matlab.ui.control.TextArea
        GerarMatrizButton              matlab.ui.control.Button
        NmerodaPCALabel                matlab.ui.control.Label
        PCAEditField                   matlab.ui.control.NumericEditField
        DiasEditField                  matlab.ui.control.EditField
        SalvarMatrizButton             matlab.ui.control.Button
        DigiteosdiasdesejadosLabel     matlab.ui.control.Label
        SelecionarMatrizButton         matlab.ui.control.Button
        SelecionarPastacomosdadosButton  matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        matrix_list    % Matriz de dados do PCA
        lat_dados      % Vetor de latitudes
        lon_dados      % Vetor de longitudes
        ArquivosSelecionados
        CaminhoDosDados
        EstacoesAtivas = {}
        DiasSelecionados = []
        PastaSelecionada
    end
    

    methods (Access = private)
        function cliqueNaEstacao(app, sigla)
            if ismember(sigla, app.EstacoesAtivas)
                app.EstacoesAtivas(strcmp(app.EstacoesAtivas, sigla)) = [];
            else
                app.EstacoesAtivas{end+1} = sigla;
            end
            app.atualizarMapa(); 
        end

        function atualizarMapa(app)
            cla(app.UIAxes); 
            hold(app.UIAxes, 'on');
        
            % 2. Tenta desenhar o contorno do Brasil primeiro (para ficar ao fundo)
            try
                % Importante: O arquivo deve estar no Path ou na pasta atual
                brasil = shaperead('BR_UF_2019.shp', 'UseGeoCoords', true);
                for k = 1:length(brasil)
                    plot(app.UIAxes, brasil(k).Lon, brasil(k).Lat, 'Color', [0.5 0.5 0.5], 'LineWidth', 1);
                end
            catch
                % Se falhar, avisa no console mas continua para plotar as estações
                fprintf('Aviso: Não foi possível carregar o Shapefile no UIAxes.\n');
            end
        
            % 3. Carrega e plota as estações
            if exist('estacoes_coords.txt', 'file')
                tabela = readtable('estacoes_coords.txt');
                
                for i = 1:height(tabela)
                    sigla = tabela.Sigla{i};
                    cor = 'red'; 
                    
                    if ismember(sigla, app.EstacoesAtivas)
                        cor = 'green';
                    end
                    
                    % Plota a bolinha
                    scatter(app.UIAxes, tabela.Longitude(i), tabela.Latitude(i), 60, ...
                        cor, 'filled', 'ButtonDownFcn', @(src, event) app.cliqueNaEstacao(sigla), ...
                        'PickableParts', 'all');
                    
                    % Plota o nome da estação
                    text(app.UIAxes, tabela.Longitude(i)+0.3, tabela.Latitude(i), sigla, ...
                        'FontSize', 7, 'FontWeight', 'normal', 'HitTest', 'off');
                end
            end
        
            % 4. Ajusta os limites para focar no Brasil
            xlim(app.UIAxes, [-75, -34]);
            ylim(app.UIAxes, [-35, 6]);
            grid(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % 1. Localiza os arquivos na pasta atual
            app.CaminhoDosDados = pwd; 
            
            % 2. Tenta carregar as estações do seu TXT para o mapa
            if exist('estacoes_coords.txt', 'file')
                try
                    tabela = readtable('estacoes_coords.txt');
                    app.EstacoesAtivas = tabela.Sigla;
                    app.atualizarMapa(); 
                catch
                    uialert(app.UIFigure, 'Erro ao ler estacoes_coords.txt', 'Erro');
                end
            else
                uialert(app.UIFigure, 'Aviso: estacoes_coords.txt não encontrado!', 'Arquivo Faltando');
            end
            
            % 3. Tenta carregar o arquivo X_anom.mat se ele já existir
            if exist('X_anom.mat', 'file')
                try
                    conteudo = load('X_anom.mat');
                    % Verifica se salvou como 'teste' ou 'matrix_list'
                    if isfield(conteudo, 'teste')
                        app.matrix_list = conteudo.teste;
                    elseif isfield(conteudo, 'matrix_list')
                        app.matrix_list = conteudo.matrix_list;
                    end
                catch
                end
            end
            app.UIFigure.Name = 'PCA - Principal Component Analysis';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {533, 533};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {302, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: PlotarimagemButton
        function PlotarimagemButtonPushed(app, event)
%         % 1. Verifica se os dados existem
%             if isempty(app.matrix_list)
%                 uialert(app.UIFigure, 'Gere ou selecione a matriz primeiro!', 'Erro');
%                 return;
%             end
%         
%             % 2. Coordenadas e Nomes Completos (Lista Mestra)
%             coords_master = [-9.749 -36.653; -11.306 -41.859; -17.555 -39.743; -14.888 -40.803; -1.409 -48.463; 2.845 -60.701; -15.947 -47.878; -3.877 -38.426; -3.878 -38.426; -20.311 -40.319];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
%             names_master = {'ALAR','BAIR','BATF','BAVC','BELE','BOAV','BRAZ','BRFT','CEEU','CEFE','CEFT','CHPI','CUIB','GOJA','ILHA','IMBT','IMPZ','MABA','MGBH','MGIN','MGMC','MGRP','MGUB','MSCG','MTCN','MTCO','MTSF','MTSR','NAUS','NEIA','ONRJ','PAIT','PBCG','PEPE','PISR','PITN','POAL','POLI','POVE','PPTE','PRGU','PRMA','RIOB','RIOD','RJCG','RNMO','RNNA','ROCD','ROGM','ROJI','ROSA','SAGA','SALU','SAVO','SCCH','SCLA','SJRP','SMAR','SPAR','SSA1','TOGU','TOPL','UFPR','VICO'};
%         
%             lat = coords_master(:,1);
%             lon = coords_master(:,2);
%         
%             % --- O SEGREDO DA SELEÇÃO INTERATIVA ---
%             % Identifica quem NÃO está na lista de EstacoesAtivas (clicadas pelo usuário)
%             % Se você quer que o clique REMOVA a estação, a lógica é:
%             idx_remove = ~ismember(names_master, app.EstacoesAtivas);
%         
%             % Aplica a remoção
%             lat(idx_remove) = [];
%             lon(idx_remove) = [];
%             % names_master(idx_remove) = []; % Não é necessário para o plot, mas pode remover se quiser
%         
%             % 4. PCA com dados filtrados
%             X = app.matrix_list;
%             X(isnan(X)) = 0;
%             
%             % Remove as colunas da matriz que correspondem às estações "desativadas"
%             % Nota: Certifique-se que o número de colunas de X é igual ao de names_master
%             if size(X, 2) == length(names_master)
%                 X(:, idx_remove) = [];
%             end
%             
%             [coeff, score, ~, ~, ~] = pca(X);
%         
%             % nEOF do campo de edição
%             nEOF = app.PCAEditField.Value;
%             if nEOF > size(coeff,2) || nEOF < 1, nEOF = 1; end
%             eof_val = coeff(:, nEOF);
%         
%             % 5. Simetrização (com os dados que sobraram)
%             lat_all = [lat; lat; abs(lat); abs(lat); lat; abs(lat)+2*min(lat); abs(lat)+2*min(lat); abs(lat); abs(lat)+2*min(lat)];
%             lon_all = [lon; abs(lon)+2*max(lon); lon; abs(lon)+2*max(lon); abs(lon)+2*min(lon); lon; abs(lon)+2*max(lon); abs(lon)+2*min(lon); abs(lon)+2*min(lon)];
%             eof_all = repmat(eof_val, 9, 1);
%         
%             % 6. Interpolação e Plot (como antes)
%             lon_vec = linspace(min(lon_all)-1, max(lon_all)+1, 400);
%             lat_vec = linspace(min(lat_all)-1, max(lat_all)+1, 400);
%             [LonG, LatG] = meshgrid(lon_vec, lat_vec);
%             Z = griddata(lon_all, lat_all, eof_all, LonG, LatG, 'natural');
%         
%             % Criação da figura
%             fig = figure('Color', [1 1 1]);
%             ax = axes('Parent', fig);
%             hold(ax, 'on');
%         
%             contourf(ax, LonG, LatG, Z, 100, 'LineColor', 'none');
%             colormap(ax, jet);
%             colorbar(ax);
%         
%             % Contorno do Brasil
%             try
%                 brasil = shaperead('BR_UF_2019.shp', 'UseGeoCoords', true);
%                 for k = 1:length(brasil)
%                     plot(ax, brasil(k).Lon, brasil(k).Lat, 'k', 'LineWidth', 1.5);
%                 end
%             catch
%                 warning('Shapefile não encontrado.');
%             end
%         
%             % Plota apenas as estações que sobraram no cálculo
%             scatter(ax, lon, lat, 30, 'k', 'filled');
%         
%             % Limites e formatação
%             xlim(ax, [-67, -36]);
%             ylim(ax, [-30, 3]);
%             xlabel(ax, 'Longitude'); ylabel(ax, 'Latitude');
%             title(ax, ['Padrão Espacial Interativo - EOF ', num2str(nEOF)]);
%             axis(ax, 'equal');
%             xlim(ax, [-67, -36]); ylim(ax, [-30, 3]);
%             hold(ax, 'off');

            if isempty(app.matrix_list)
                uialert(app.UIFigure, 'Gere ou selecione a matriz primeiro!', 'Erro');
                return;
            end
        
            names_master = {'ALAR','BAIR','BATF','BAVC','BELE','BOAV','BRAZ','BRFT', ...
                'CEEU','CEFE','CEFT','CHPI','CUIB','GOJA','ILHA','IMBT', ...
                'IMPZ','MABA','MGBH','MGIN','MGMC','MGRP','MGUB','MSCG', ...
                'MTCN','MTCO','MTSF','MTSR','NAUS','NEIA','ONRJ','PAIT', ...
                'PBCG','PEPE','PISR','PITN','POAL','POLI','POVE','PPTE', ...
                'PRGU','PRMA','RIOB','RIOD','RJCG','RNMO','RNNA','ROCD', ...
                'ROGM','ROJI','ROSA','SAGA','SALU','SAVO','SCCH','SCLA', ...
                'SJRP','SMAR','SPAR','SSA1','TOGU','TOPL','UFPR','VICO'}; % só vale para o brasil
        
            % Chama a função com EOF1
            [LonG, LatG, Z, lat_pts, lon_pts] = calcularPCAApp(app.matrix_list, names_master, app.EstacoesAtivas, app.PCAEditField.Value);        
            % Cria figura e plota
            fig = figure('Color',[1 1 1]);
            ax = axes('Parent', fig);
            hold(ax, 'on');
            contourf(ax, LonG, LatG, Z, 100, 'LineColor', 'none');
            colormap(ax, jet); colorbar(ax);
            
            scatter(ax, lon_pts, lat_pts, 30, 'k', 'filled');
        
            % Contorno do Brasil
            try
                brasil = shaperead('BR_UF_2019.shp', 'UseGeoCoords', true);
                for k = 1:length(brasil)
                    plot(ax, brasil(k).Lon, brasil(k).Lat, 'k', 'LineWidth', 1.5);
                end
            catch
                warning('Shapefile não encontrado.');
            end
        
            xlabel(ax,'Longitude'); ylabel(ax,'Latitude');
            title(ax,['Padrão Espacial Interativo - EOF ', num2str(app.PCAEditField.Value)]);
            axis(ax,'equal'); xlim(ax,[-67 -36]); ylim(ax,[-30 3]); % isso aqui só vale para o brasil
            hold(ax,'off');
        end

        % Button pushed function: AdicionarDiaSelecionadoButton
        function AdicionarDiaSelecionadoButtonPushed(app, event)
            % Lê o dia que você digitou na caixa branca (DiasEditField)
            dia_digitado = str2double(app.DiasEditField.Value);
            
            % Validação simples para não travar o programa
            if isnan(dia_digitado) || dia_digitado < 1 || dia_digitado > 31
                uialert(app.UIFigure, 'Digite um dia entre 1 e 31 na caixa!', 'Erro de Entrada');
                return;
            end

            % Adiciona na lista se já não estiver lá
            if ~ismember(dia_digitado, app.DiasSelecionados)
                app.DiasSelecionados = [app.DiasSelecionados, dia_digitado];
                
                % Mostra na área de texto grande (TextArea)
                app.DiasTextArea.Value = [app.DiasTextArea.Value; {['Dia adicionado: ' num2str(dia_digitado)]}];
                
                % Limpa a caixinha pra você digitar o próximo dia mais rápido
                app.DiasEditField.Value = '';
            end
        end

        % Button pushed function: GerarMatrizButton
        function GerarMatrizButtonPushed(app, event)
        dias = app.DiasSelecionados;  % pega os dias adicionados pelo usuário
            
            if isempty(dias)
                uialert(app.UIFigure,'Selecione dias primeiro','Erro');
                return
            end
        
            try
                app.GerarMatrizButton.Text = 'Processando...';
                drawnow;
                
                % Chama a função de geração da matriz
                app.matrix_list = gerar_matriz_TEC(app.PastaSelecionada, dias);
                
                uialert(app.UIFigure,'Matriz criada com sucesso','OK');
            catch ME
                uialert(app.UIFigure, ['Erro: ' ME.message], 'Erro');
            end
            
            app.GerarMatrizButton.Text = 'Gerar Matriz';
        end

        % Button pushed function: SalvarMatrizButton
        function SalvarMatrizButtonPushed(app, event)
            if isempty(app.matrix_list)

            uialert(app.UIFigure,'Nenhuma matriz gerada ainda','Erro')
            return
            
            end
            
            [arquivo,caminho] = uiputfile({'*.mat';'*.txt'},'Salvar matriz');
            
            if isequal(arquivo,0)
            return
            end
            
            nome = fullfile(caminho,arquivo);
            
            [~,~,ext] = fileparts(nome);
            
            switch ext
            
            case '.mat'
            
            matrix_list = app.matrix_list;
            save(nome,'matrix_list');
            
            case '.txt'
            
            writematrix(app.matrix_list,nome,'Delimiter',' ');
            
            end
            
            uialert(app.UIFigure,'Arquivo salvo com sucesso','OK')
        end

        % Button pushed function: SelecionarMatrizButton
        function SelecionarMatrizButtonPushed(app, event)
            app.SelecionarMatrizButton.Text = 'Selecionar Matriz';
            
            % 1. Abre o gerenciador de arquivos
            [arquivo, caminho] = uigetfile({'*.mat';'*.txt'}, 'Selecione uma matriz existente');
            
            % Se o usuário cancelar, sai da função
            if isequal(arquivo, 0)
                return;
            end
            
            nome_completo = fullfile(caminho, arquivo);
            [~, ~, ext] = fileparts(nome_completo);
            
            try
                if strcmp(ext, '.mat')
                    % Se for .mat, carrega o conteúdo
                    conteudo = load(nome_completo);
                    campos = fieldnames(conteudo);
                    
                    % Tenta encontrar a variável dentro do .mat (pega a primeira ou matrix_list)
                    if isfield(conteudo, 'matrix_list')
                        app.matrix_list = conteudo.matrix_list;
                    else
                        app.matrix_list = conteudo.(campos{1});
                    end
                    
                elseif strcmp(ext, '.txt')
                    % Se for .txt, lê como matriz numérica
                    app.matrix_list = readmatrix(nome_completo);
                end
                
                % Feedback para o usuário
                uialert(app.UIFigure, ['Matriz carregada com sucesso: ' arquivo], 'Sucesso', 'Icon', 'success');
                
                % Opcional: Avisar na área de texto que uma matriz externa foi carregada
                app.DiasTextArea.Value = [app.DiasTextArea.Value; {['Arquivo carregado: ' arquivo]}];
                
            catch ME
                uialert(app.UIFigure, ['Erro ao carregar arquivo: ' ME.message], 'Erro');
            end
        end

        % Value changed function: DiasEditField
        function DiasEditFieldValueChanged(app, event)
        % Pega o valor digitado
        dia_digitado = str2double(app.DiasEditField.Value);
        
        % Validação simples
        if isnan(dia_digitado) || dia_digitado < 1 || dia_digitado > 31
            uialert(app.UIFigure, 'Digite um dia entre 1 e 31 na caixa!', 'Erro de Entrada');
            return;
        end
    
        % Adiciona na lista se ainda não estiver
        if ~ismember(dia_digitado, app.DiasSelecionados)
            app.DiasSelecionados = [app.DiasSelecionados, dia_digitado];
            % Atualiza a área de texto
            app.DiasTextArea.Value = [app.DiasTextArea.Value; {['Dia adicionado: ' num2str(dia_digitado)]}];
        end
    
        % Limpa a caixa de edição para o próximo dia
        app.DiasEditField.Value = '';
        end

        % Button pushed function: SelecionarPastacomosdadosButton
        function SelecionarPastacomosdadosButtonPushed(app, event)
            pasta = uigetdir(pwd, 'Selecione a pasta com os arquivos TEC');
            if pasta ~= 0
                app.PastaSelecionada = pasta; % salva na propriedade do app
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlotarimagemButton = uibutton(app.LeftPanel, 'push');
            app.PlotarimagemButton.ButtonPushedFcn = createCallbackFcn(app, @PlotarimagemButtonPushed, true);
            app.PlotarimagemButton.Text = 'Plotar imagem';
            app.AdicionarDiaSelecionadoButton = uibutton(app.LeftPanel, 'push');
            app.AdicionarDiaSelecionadoButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionarDiaSelecionadoButtonPushed, true);
            app.AdicionarDiaSelecionadoButton.Text = 'Adicionar Dia Selecionado';
            app.readediasselecionadosLabel = uilabel(app.LeftPanel);
            app.readediasselecionadosLabel.Text = 'Área de dias selecionados';
            app.DiasTextArea = uitextarea(app.LeftPanel);
            app.GerarMatrizButton = uibutton(app.LeftPanel, 'push');
            app.GerarMatrizButton.ButtonPushedFcn = createCallbackFcn(app, @GerarMatrizButtonPushed, true);
            app.GerarMatrizButton.Text = 'Gerar Matriz';
            app.NmerodaPCALabel = uilabel(app.LeftPanel);
            app.NmerodaPCALabel.Text = 'Número da PCA :';
            app.PCAEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DiasEditField = uieditfield(app.LeftPanel, 'text');
            app.DiasEditField.ValueChangedFcn = createCallbackFcn(app, @DiasEditFieldValueChanged, true);
            app.SalvarMatrizButton = uibutton(app.LeftPanel, 'push');
            app.SalvarMatrizButton.ButtonPushedFcn = createCallbackFcn(app, @SalvarMatrizButtonPushed, true);
            app.SalvarMatrizButton.Text = 'Salvar Matriz';
            app.DigiteosdiasdesejadosLabel = uilabel(app.LeftPanel);
            app.DigiteosdiasdesejadosLabel.Text = 'Digite os dias desejados:';
            app.SelecionarMatrizButton = uibutton(app.LeftPanel, 'push');
            app.SelecionarMatrizButton.ButtonPushedFcn = createCallbackFcn(app, @SelecionarMatrizButtonPushed, true);
            app.SelecionarMatrizButton.Text = 'Selecionar Matriz';
            app.SelecionarPastacomosdadosButton = uibutton(app.LeftPanel, 'push');
            app.SelecionarPastacomosdadosButton.ButtonPushedFcn = createCallbackFcn(app, @SelecionarPastacomosdadosButtonPushed, true);
            app.SelecionarPastacomosdadosButton.Text = 'Selecionar Pasta com os dados';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Estações ')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfaceG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end