classdef InterfaceG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        PlotarimagemButton             matlab.ui.control.Button
        AdicionarDiaSelecionadoButton  matlab.ui.control.Button
        readediasselecionadosLabel     matlab.ui.control.Label
        DiasTextArea                   matlab.ui.control.TextArea
        GerarMatrizButton              matlab.ui.control.Button
        NmerodaPCALabel                matlab.ui.control.Label
        PCAEditField                   matlab.ui.control.NumericEditField
        DiasEditField                  matlab.ui.control.EditField
        SalvarMatrizButton             matlab.ui.control.Button
        DigiteosdiasdesejadosLabel     matlab.ui.control.Label
        SelecionarMatrizButton         matlab.ui.control.Button
        SelecionarPastacomosdadosButton  matlab.ui.control.Button
        SelecionarMesInteiroButton     matlab.ui.control.Button
        AnoLabel                       matlab.ui.control.Label
        AnoEditField                   matlab.ui.control.NumericEditField
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        DropDownLabel                  matlab.ui.control.Label
        PaisesDropDown                 matlab.ui.control.DropDown
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        matrix_list    % Matriz de dados do PCA
        lat_dados      % Vetor de latitudes
        lon_dados      % Vetor de longitudes
        ArquivosSelecionados
        CaminhoDosDados
        EstacoesAtivas = {}
        DiasSelecionados = []
        PastaSelecionada
        dadosRegiao
    end
    

    methods (Access = private)
        function cliqueNaEstacao(app, sigla)
            if ismember(sigla, app.EstacoesAtivas)
                app.EstacoesAtivas(strcmp(app.EstacoesAtivas, sigla)) = [];
            else
                app.EstacoesAtivas{end+1} = sigla;
            end
            app.atualizarMapa(); 
        end

        function atualizarMapa(app)
            cla(app.UIAxes); 
            hold(app.UIAxes, 'on');
        
            try
                mapa = shaperead(app.dadosRegiao.shapefile, 'UseGeoCoords', true);
                for k = 1:length(mapa)
                    plot(app.UIAxes, mapa(k).Lon, mapa(k).Lat, 'Color', [0.5 0.5 0.5], 'LineWidth', 1);
                end
            catch
                fprintf('Aviso: Não foi possível carregar o Shapefile no UIAxes.\n');
            end

        tabela = readtable('estacoes_coords.txt');
            for i = 1:height(tabela)
                sigla = tabela.Sigla{i};
                cor = 'red';
                if ismember(sigla, app.EstacoesAtivas)
                    cor = 'green';
                end
                scatter(app.UIAxes, tabela.Longitude(i), tabela.Latitude(i), 60, cor, 'filled', ...
                    'ButtonDownFcn', @(src, event) app.cliqueNaEstacao(sigla), 'PickableParts', 'all');
                text(app.UIAxes, tabela.Longitude(i)+0.3, tabela.Latitude(i), sigla, ...
                    'FontSize', 7, 'HitTest', 'off');
            end
        
            % 4. Ajusta os limites para focar no Brasil
%             xlim(app.UIAxes, [-75, -34]);
%             ylim(app.UIAxes, [-35, 6]);
%             grid(app.UIAxes, 'on');
%             axis(app.UIAxes, 'equal');
            xlim(app.UIAxes, app.dadosRegiao.xlim);
            ylim(app.UIAxes, app.dadosRegiao.ylim);
            axis(app.UIAxes, 'equal');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % 1. Localiza os arquivos na pasta atual
            app.CaminhoDosDados = pwd; 
            
            tabela = readtable('estacoes_coords.txt');
            
            app.dadosRegiao = struct();  % inicializa como struct vazia
            
            app.dadosRegiao.estacoes = tabela.Sigla;
            app.dadosRegiao.lat = tabela.Latitude;
            app.dadosRegiao.lon = tabela.Longitude;
            
            app.dadosRegiao.xlim = [-67 -36];
            app.dadosRegiao.ylim = [-30 3];
            app.dadosRegiao.shapefile = 'Brasil.shp';
            
            % 3. Tenta carregar o arquivo X_anom.mat se ele já existir
            if exist('X_anom.mat', 'file')
                try
                    conteudo = load('X_anom.mat');
                    % Verifica se salvou como 'teste' ou 'matrix_list'
                    if isfield(conteudo, 'teste')
                        app.matrix_list = conteudo.teste;
                    elseif isfield(conteudo, 'matrix_list')
                        app.matrix_list = conteudo.matrix_list;
                    end
                catch
                end
            end
            app.UIFigure.Name = 'PCA - Principal Component Analysis';
            
            % Inicializa todas as estações como ativas
            app.EstacoesAtivas = app.dadosRegiao.estacoes;
            app.atualizarMapa();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {630, 630};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {302, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: PlotarimagemButton
        function PlotarimagemButtonPushed(app, event)
%             if isempty(app.matrix_list)
%                 uialert(app.UIFigure, 'Gere ou selecione a matriz primeiro!', 'Erro');
%                 return;
%             end
% 
%             names_master = app.dadosRegiao.estacoes;
%             lat = app.dadosRegiao.lat;
%             lon = app.dadosRegiao.lon;
%             
%             % Chama a função com EOF1
%             [LonG, LatG, Z, lat_pts, lon_pts] = calcularPCAApp(app.matrix_list, names_master, app.EstacoesAtivas, app.PCAEditField.Value, lat, lon);        
%             % Cria figura e plota
%             fig = figure('Color',[1 1 1]);
%             ax = axes('Parent', fig);
%             hold(ax, 'on');
%             contourf(ax, LonG, LatG, Z, 100, 'LineColor', 'none');
%             colormap(ax, jet); colorbar(ax);
%             
%             scatter(ax, lon_pts, lat_pts, 30, 'k', 'filled');
%         
%             % Contorno do Brasil
%             try
%                 brasil = shaperead('Brasil.shp', 'UseGeoCoords', true);
%                 for k = 1:length(brasil)
%                     plot(ax, brasil(k).Lon, brasil(k).Lat, 'k', 'LineWidth', 1.5);
%                 end
%             catch
%                 warning('Shapefile não encontrado.');
%             end
%         
%             xlabel(ax,'Longitude'); ylabel(ax,'Latitude');
%             title(ax,['Padrão Espacial Interativo - EOF ', num2str(app.PCAEditField.Value)]);
%             axis(ax,'equal'); 
% %             xlim(ax,[-67 -36]); 
% %             ylim(ax,[-30 3]); % isso aqui só vale para o brasil
%             xlim(ax, app.dadosRegiao.xlim);
%             ylim(ax, app.dadosRegiao.ylim);
%             hold(ax,'off');
        % 1. Verifica se existe matriz carregada
            if isempty(app.matrix_list)
                uialert(app.UIFigure, 'Gere ou selecione a matriz primeiro!', 'Erro');
                return;
            end
        
            % 2. Pega os dados "Master" (Gabarito do País atual)
            nomes_master = app.dadosRegiao.estacoes;
            lat_master = app.dadosRegiao.lat;
            lon_master = app.dadosRegiao.lon;
            
            % 3. Pega as siglas que estão REALMENTE na matriz (Filtro do Ano)
            siglas_da_matriz = app.EstacoesAtivas; 
        
            % --- LÓGICA DE ALINHAMENTO ---
            % Precisamos encontrar onde as siglas da matriz estão no Master 
            % para pegar as Latitudes e Longitudes corretas.
            [presente, idx] = ismember(siglas_da_matriz, nomes_master);
            
            % Filtramos as coordenadas para terem o mesmo tamanho da matriz
            lat_filtrada = lat_master(idx(presente));
            lon_filtrada = lon_master(idx(presente));
            
            try
                % 4. Chama o cálculo do PCA/EOF
                % Passamos a matriz, as siglas dela e as coordenadas filtradas
                [LonG, LatG, Z, lat_pts, lon_pts] = calcularPCAApp(app.matrix_list, ...
                    siglas_da_matriz, siglas_da_matriz, app.PCAEditField.Value, ...
                    lat_filtrada, lon_filtrada);        
                
                % 5. Criação da figura de visualização
                fig = figure('Color',[1 1 1], 'Name', ['EOF Result - ' app.PaisesDropDown.Value]);
                ax = axes('Parent', fig);
                hold(ax, 'on');
                
                % Plot do contorno colorido (Interpolação)
                contourf(ax, LonG, LatG, Z, 100, 'LineColor', 'none');
                colormap(ax, jet); 
                cb = colorbar(ax);
                ylabel(cb, 'Amplitude');
                
                % Plota os pontos pretos (estações que contribuíram para o cálculo)
                scatter(ax, lon_pts, lat_pts, 40, 'k', 'filled');
            
                % 6. Desenha o Shapefile (Brasil, Argentina, etc)
                try
                    shape = shaperead(app.dadosRegiao.shapefile, 'UseGeoCoords', true);
                    for k = 1:length(shape)
                        plot(ax, shape(k).Lon, shape(k).Lat, 'k', 'LineWidth', 1.2);
                    end
                catch
                    fprintf('Aviso: Shapefile "%s" não encontrado.\n', app.dadosRegiao.shapefile);
                end
            
                % Configurações de eixos baseadas no país selecionado
                xlabel(ax,'Longitude'); ylabel(ax,'Latitude');
                title(ax, sprintf('Padrão Espacial %s - EOF %d (Ano: %d)', ...
                    app.PaisesDropDown.Value, app.PCAEditField.Value, app.AnoEditField.Value));
                
                axis(ax,'equal'); 
                xlim(ax, app.dadosRegiao.xlim);
                ylim(ax, app.dadosRegiao.ylim);
                grid(ax, 'on');
                hold(ax,'off');
        
            catch ME
                uialert(app.UIFigure, ['Erro no processamento: ' ME.message], 'Erro de Execução');
            end
        end

        % Button pushed function: AdicionarDiaSelecionadoButton
        function AdicionarDiaSelecionadoButtonPushed(app, event)
            % Lê o dia que você digitou na caixa branca (DiasEditField)
            dia_digitado = str2double(app.DiasEditField.Value);
            
            % Validação simples para não travar o programa
            if isnan(dia_digitado) || dia_digitado < 1 || dia_digitado > 31
                uialert(app.UIFigure, 'Digite um dia entre 1 e 31 na caixa!', 'Erro de Entrada');
                return;
            end

            % Adiciona na lista se já não estiver lá
            if ~ismember(dia_digitado, app.DiasSelecionados)
                app.DiasSelecionados = [app.DiasSelecionados, dia_digitado];
                
                % Mostra na área de texto grande (TextArea)
                app.DiasTextArea.Value = [app.DiasTextArea.Value; {['Dia adicionado: ' num2str(dia_digitado)]}];
                
                % Limpa a caixinha pra você digitar o próximo dia mais rápido
                app.DiasEditField.Value = '';
            end
        end

        % Button pushed function: GerarMatrizButton
        function GerarMatrizButtonPushed(app, event)
%         dias = app.DiasSelecionados;  % pega os dias adicionados pelo usuário
%         ano = app.AnoEditField.Value;
%         
%             if isempty(dias)
%                 uialert(app.UIFigure,'Selecione dias primeiro','Erro');
%                 return
%             end
%         
%             try
%                 app.GerarMatrizButton.Text = 'Processando...';
%                 drawnow;
%                 
%                 % Chama a função de geração da matriz
%                 app.matrix_list = gerar_matriz_TEC(app.PastaSelecionada, dias, app.dadosRegiao.estacoes);
%                 
%                 uialert(app.UIFigure,'Matriz criada com sucesso','OK');
%             catch ME
%                 uialert(app.UIFigure, ['Erro: ' ME.message], 'Erro');
%             end          

            % 1. Pega os parâmetros da Interface
            dias = app.DiasSelecionados;  
            ano = app.AnoEditField.Value; 
            
            % 2. Pega o "Gabarito" do país selecionado no momento
            % (app.dadosRegiao.estacoes deve conter as siglas do Brasil, Argentina, etc.)
            estacoes_do_pais = app.dadosRegiao.estacoes; 
            
            % Validações Iniciais
            if isempty(dias)
                uialert(app.UIFigure, 'Selecione os dias primeiro!', 'Erro');
                return
            end
            
            if isempty(ano) || ano <= 0
                uialert(app.UIFigure, 'Digite um ano válido!', 'Erro');
                return
            end
        
            try
                % Interface: Feedback visual
                app.GerarMatrizButton.Text = 'Processando...';
                app.GerarMatrizButton.Enable = 'off'; 
                drawnow;
                
                % CHAMADA DA FUNÇÃO: 
                % Enviamos a lista de estações do país selecionado e o ano alvo
                [matriz_temp, siglas] = gerar_matriz_TEC(app.PastaSelecionada, dias, estacoes_do_pais, ano);
                
                % Salva os resultados nas propriedades globais do App
                app.matrix_list = matriz_temp;
                app.EstacoesAtivas = siglas; % Aqui ficam apenas as que tinham arquivo TXT
                
                uialert(app.UIFigure, sprintf('Sucesso! Matriz gerada com %d estações para o ano %d.', ...
                    length(siglas), ano), 'Sucesso');
                
            catch ME
                uialert(app.UIFigure, ['Erro: ' ME.message], 'Erro');
            end
            
            % Restaura o estado do botão
            app.GerarMatrizButton.Text = 'Gerar Matriz';
            app.GerarMatrizButton.Enable = 'on';
        end

        % Button pushed function: SalvarMatrizButton
        function SalvarMatrizButtonPushed(app, event)
            if isempty(app.matrix_list)

            uialert(app.UIFigure,'Nenhuma matriz gerada ainda','Erro')
            return
            
            end
            
            [arquivo,caminho] = uiputfile({'*.mat';'*.txt'},'Salvar matriz');
            
            if isequal(arquivo,0)
            return
            end
            
            nome = fullfile(caminho,arquivo);
            
            [~,~,ext] = fileparts(nome);
            
            switch ext
            
            case '.mat'
            
            matrix_list = app.matrix_list;
            save(nome,'matrix_list');
            
            case '.txt'
            
            writematrix(app.matrix_list,nome,'Delimiter',' ');
            
            end
            
            uialert(app.UIFigure,'Arquivo salvo com sucesso','OK')
        end

        % Button pushed function: SelecionarMatrizButton
        function SelecionarMatrizButtonPushed(app, event)
            app.SelecionarMatrizButton.Text = 'Selecionar Matriz';
            
            % 1. Abre o gerenciador de arquivos
            [arquivo, caminho] = uigetfile({'*.mat';'*.txt'}, 'Selecione uma matriz existente');
            
            % Se o usuário cancelar, sai da função
            if isequal(arquivo, 0)
                return;
            end
            
            nome_completo = fullfile(caminho, arquivo);
            [~, ~, ext] = fileparts(nome_completo);
            
            try
                if strcmp(ext, '.mat')
                    % Se for .mat, carrega o conteúdo
                    conteudo = load(nome_completo);
                    campos = fieldnames(conteudo);
                    
                    % Tenta encontrar a variável dentro do .mat (pega a primeira ou matrix_list)
                    if isfield(conteudo, 'matrix_list')
                        app.matrix_list = conteudo.matrix_list;
                    else
                        app.matrix_list = conteudo.(campos{1});
                    end
                    
                elseif strcmp(ext, '.txt')
                    % Se for .txt, lê como matriz numérica
                    app.matrix_list = readmatrix(nome_completo);
                end
                
                % Feedback para o usuário
                uialert(app.UIFigure, ['Matriz carregada com sucesso: ' arquivo], 'Sucesso', 'Icon', 'success');
                
                % Opcional: Avisar na área de texto que uma matriz externa foi carregada
                app.DiasTextArea.Value = [app.DiasTextArea.Value; {['Arquivo carregado: ' arquivo]}];
                
            catch ME
                uialert(app.UIFigure, ['Erro ao carregar arquivo: ' ME.message], 'Erro');
            end
        end

        % Value changed function: DiasEditField
        function DiasEditFieldValueChanged(app, event)
        % Pega o valor digitado
        dia_digitado = str2double(app.DiasEditField.Value);
        
        % Validação simples
        if isnan(dia_digitado) || dia_digitado < 1 || dia_digitado > 31
            uialert(app.UIFigure, 'Digite um dia entre 1 e 31 na caixa!', 'Erro de Entrada');
            return;
        end
    
        % Adiciona na lista se ainda não estiver
        if ~ismember(dia_digitado, app.DiasSelecionados)
            app.DiasSelecionados = [app.DiasSelecionados, dia_digitado];
            % Atualiza a área de texto
            app.DiasTextArea.Value = [app.DiasTextArea.Value; {['Dia adicionado: ' num2str(dia_digitado)]}];
        end
    
        % Limpa a caixa de edição para o próximo dia
        app.DiasEditField.Value = '';
        end

        % Button pushed function: SelecionarPastacomosdadosButton
        function SelecionarPastacomosdadosButtonPushed(app, event)
            pasta = uigetdir(pwd, 'Selecione a pasta com os arquivos TEC');
            if pasta ~= 0
                app.PastaSelecionada = pasta; % salva na propriedade do app
            end
        end

        % Value changed function: PaisesDropDown
        function PaisesDropDownValueChanged(app, event)
%             value = app.PaisesDropDown.Value;
%             switch app.DropDown.Value
%                 
%                 case 'Brasil'
%                     xlim(ax, [-67 -36])
%                     ylim(ax, [-30 3])
%                     
%                 case 'Argentina'
%                     xlim(ax, [-75 -50])
%                     ylim(ax, [-55 -20])
%             end
        end

        % Button pushed function: SelecionarMesInteiroButton
        function SelecionarMesInteiroButtonPushed(app, event)
            % Define todos os dias do mês
            app.DiasSelecionados = 1:31;
            
            % Atualiza a área de texto para mostrar todos os dias selecionados
            textoDias = cell(31,1);
            for i = 1:31
                textoDias{i} = ['Dia adicionado: ' num2str(i)];
            end
            app.DiasTextArea.Value = textoDias;
            
            % Limpa a caixa de edição, caso esteja preenchida
            app.DiasEditField.Value = '';
            
            % Feedback opcional
            uialert(app.UIFigure, 'Todos os dias do mês foram selecionados!', 'Mês Inteiro');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlotarimagemButton = uibutton(app.LeftPanel, 'push');
            app.PlotarimagemButton.ButtonPushedFcn = createCallbackFcn(app, @PlotarimagemButtonPushed, true);
            app.PlotarimagemButton.Text = 'Plotar imagem';
            app.AdicionarDiaSelecionadoButton = uibutton(app.LeftPanel, 'push');
            app.AdicionarDiaSelecionadoButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionarDiaSelecionadoButtonPushed, true);
            app.AdicionarDiaSelecionadoButton.Text = 'Adicionar Dia Selecionado';
            app.readediasselecionadosLabel = uilabel(app.LeftPanel);
            app.readediasselecionadosLabel.Text = 'Área de dias selecionados';
            app.DiasTextArea = uitextarea(app.LeftPanel);
            app.GerarMatrizButton = uibutton(app.LeftPanel, 'push');
            app.GerarMatrizButton.ButtonPushedFcn = createCallbackFcn(app, @GerarMatrizButtonPushed, true);
            app.GerarMatrizButton.Text = 'Gerar Matriz';
            app.NmerodaPCALabel = uilabel(app.LeftPanel);
            app.NmerodaPCALabel.Text = 'Número da PCA :';
            app.PCAEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DiasEditField = uieditfield(app.LeftPanel, 'text');
            app.DiasEditField.ValueChangedFcn = createCallbackFcn(app, @DiasEditFieldValueChanged, true);
            app.SalvarMatrizButton = uibutton(app.LeftPanel, 'push');
            app.SalvarMatrizButton.ButtonPushedFcn = createCallbackFcn(app, @SalvarMatrizButtonPushed, true);
            app.SalvarMatrizButton.Text = 'Salvar Matriz';
            app.DigiteosdiasdesejadosLabel = uilabel(app.LeftPanel);
            app.DigiteosdiasdesejadosLabel.Text = 'Digite os dias desejados:';
            app.SelecionarMatrizButton = uibutton(app.LeftPanel, 'push');
            app.SelecionarMatrizButton.ButtonPushedFcn = createCallbackFcn(app, @SelecionarMatrizButtonPushed, true);
            app.SelecionarMatrizButton.Text = 'Selecionar Matriz';
            app.SelecionarPastacomosdadosButton = uibutton(app.LeftPanel, 'push');
            app.SelecionarPastacomosdadosButton.ButtonPushedFcn = createCallbackFcn(app, @SelecionarPastacomosdadosButtonPushed, true);
            app.SelecionarPastacomosdadosButton.Text = 'Selecionar Pasta com os dados';
            app.SelecionarMesInteiroButton = uibutton(app.LeftPanel, 'push');
            app.SelecionarMesInteiroButton.ButtonPushedFcn = createCallbackFcn(app, @SelecionarMesInteiroButtonPushed, true);
            app.SelecionarMesInteiroButton.Text = 'Mês Inteiro';
            app.AnoLabel = uilabel(app.LeftPanel);
            app.AnoLabel.Text = 'Ano';
            app.AnoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Estações ')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.DropDownLabel = uilabel(app.RightPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.PaisesDropDown = uidropdown(app.RightPanel);
            app.PaisesDropDown.Items = {'Brasil', 'Argentina'};
            app.PaisesDropDown.ValueChangedFcn = createCallbackFcn(app, @PaisesDropDownValueChanged, true);
            app.PaisesDropDown.Value = 'Brasil';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfaceG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end